classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TrainTab                       matlab.ui.container.Tab
        TrainButton_2                  matlab.ui.control.Button
        optionalLabel_2                matlab.ui.control.Label
        SelectfolderscotainingsubfoldersdataandgtLabel  matlab.ui.control.Label
        trainigfolderEditField_2Label  matlab.ui.control.Label
        trainigfolderEditField_2       matlab.ui.control.EditField
        validationfolderEditField_2Label  matlab.ui.control.Label
        validationfolderEditField_2    matlab.ui.control.EditField
        NumofepochsSpinner_2Label      matlab.ui.control.Label
        NumofepochsSpinner_2           matlab.ui.control.Spinner
        InitiallearningrateSpinner_2Label  matlab.ui.control.Label
        InitiallearningrateSpinner_2   matlab.ui.control.Spinner
        LearningratedropperiodSpinner_2Label  matlab.ui.control.Label
        LearningratedropperiodSpinner_2  matlab.ui.control.Spinner
        LearningratedropfactorSpinner_2Label  matlab.ui.control.Label
        LearningratedropfactorSpinner_2  matlab.ui.control.Spinner
        PatchsizeSpinner_2Label        matlab.ui.control.Label
        PatchsizeSpinner_2             matlab.ui.control.Spinner
        AugmentationPanel              matlab.ui.container.Panel
        Rotation90CheckBox             matlab.ui.control.CheckBox
        FliphorizonalCheckBox          matlab.ui.control.CheckBox
        FlipverticalCheckBox           matlab.ui.control.CheckBox
        TrainigdataPanel               matlab.ui.container.Panel
        TrainingdataListBox            matlab.ui.control.ListBox
        ValidationdataPanel            matlab.ui.container.Panel
        TrainingdataListBox_2          matlab.ui.control.ListBox
        StatusLabel                    matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        BatchsizeSpinnerLabel          matlab.ui.control.Label
        BatchsizeSpinner               matlab.ui.control.Spinner
        PredictTab                     matlab.ui.container.Tab
        SelecttrainednetworkandfolderwithimagestopredictLabel  matlab.ui.control.Label
        networkEditFieldLabel          matlab.ui.control.Label
        networkEditField               matlab.ui.control.EditField
        datafolderEditFieldLabel       matlab.ui.control.Label
        datafolderEditField            matlab.ui.control.EditField
        PredictButton                  matlab.ui.control.Button
        DatatopredictPanel             matlab.ui.container.Panel
        TrainingdataListBox_3          matlab.ui.control.ListBox
        StatusLabel_2                  matlab.ui.control.Label
        Button_3                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            selpath = uigetdir;
            app.trainigfolderEditField_2.Value=selpath;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            selpath = uigetdir;
            app.validationfolderEditField_2.Value=selpath;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.networkEditField.Value=[path '/' file];
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            selpath = uigetdir;
            app.datafolderEditField.Value=selpath;
        end

        % Value changing function: PatchsizeSpinner_2
        function PatchsizeSpinner_2ValueChanging(app, event)
            value  = event.Value;
            if ischar(value)
               value=str2double(value);
            end
            app.PatchsizeSpinner_2.Value=(round(value-mod(value,32)));
        end

        % Button pushed function: TrainButton_2
        function TrainButton_2Pushed(app, event)
            folder_train=app.trainigfolderEditField_2.Value;
            folder_valid=app.validationfolderEditField_2.Value;
            miniBatchSize=app.BatchsizeSpinner.Value;
            patchSize=[app.PatchsizeSpinner_2.Value app.PatchsizeSpinner_2.Value];
            augment=[app.Rotation90CheckBox.Value,app.FliphorizonalCheckBox.Value,app.FlipverticalCheckBox.Value];
            epoch=app.NumofepochsSpinner_2.Value;
            drop_period=app.LearningratedropperiodSpinner_2.Value;
            drop_faktor=app.LearningratedropfactorSpinner_2.Value;
            init_lr=app.InitiallearningrateSpinner_2.Value;

            app.StatusLabel.Text='processing';
            train(folder_train,folder_valid,miniBatchSize,patchSize,augment,epoch,drop_period,drop_faktor,init_lr)
            app.StatusLabel.Text='finised';
            
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            net_path=app.networkEditField.Value;
            folder=app.datafolderEditField.Value;
            app.StatusLabel_2.Text='processing';
            predict_net(folder,'',net_path)
            app.StatusLabel_2.Text='finised';
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.TrainButton_2 = uibutton(app.TrainTab, 'push');
            app.TrainButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrainButton_2Pushed, true);
            app.TrainButton_2.Text = 'Train';
            app.optionalLabel_2 = uilabel(app.TrainTab);
            app.optionalLabel_2.Text = '(optional)';
            app.SelectfolderscotainingsubfoldersdataandgtLabel = uilabel(app.TrainTab);
            app.SelectfolderscotainingsubfoldersdataandgtLabel.Text = 'Select folders cotaining subfolders "data" and "gt":';
            app.trainigfolderEditField_2Label = uilabel(app.TrainTab);
            app.trainigfolderEditField_2Label.Text = 'trainig folder';
            app.trainigfolderEditField_2 = uieditfield(app.TrainTab, 'text');
            app.validationfolderEditField_2Label = uilabel(app.TrainTab);
            app.validationfolderEditField_2Label.Text = 'validation folder';
            app.validationfolderEditField_2 = uieditfield(app.TrainTab, 'text');
            app.NumofepochsSpinner_2Label = uilabel(app.TrainTab);
            app.NumofepochsSpinner_2Label.Text = 'Num. of epochs';
            app.NumofepochsSpinner_2 = uispinner(app.TrainTab);
            app.NumofepochsSpinner_2.Limits = [1 Inf];
            app.NumofepochsSpinner_2.RoundFractionalValues = 'on';
            app.NumofepochsSpinner_2.Value = 30;
            app.InitiallearningrateSpinner_2Label = uilabel(app.TrainTab);
            app.InitiallearningrateSpinner_2Label.Text = 'Initial learning rate';
            app.InitiallearningrateSpinner_2 = uispinner(app.TrainTab);
            app.InitiallearningrateSpinner_2.Step = 1e-05;
            app.InitiallearningrateSpinner_2.Limits = [0 Inf];
            app.InitiallearningrateSpinner_2.Value = 0.001;
            app.LearningratedropperiodSpinner_2Label = uilabel(app.TrainTab);
            app.LearningratedropperiodSpinner_2Label.Text = 'Learning rate drop period';
            app.LearningratedropperiodSpinner_2 = uispinner(app.TrainTab);
            app.LearningratedropperiodSpinner_2.Limits = [0 Inf];
            app.LearningratedropperiodSpinner_2.RoundFractionalValues = 'on';
            app.LearningratedropperiodSpinner_2.Value = 30;
            app.LearningratedropfactorSpinner_2Label = uilabel(app.TrainTab);
            app.LearningratedropfactorSpinner_2Label.Text = 'Learning rate drop factor';
            app.LearningratedropfactorSpinner_2 = uispinner(app.TrainTab);
            app.LearningratedropfactorSpinner_2.Step = 0.1;
            app.LearningratedropfactorSpinner_2.Limits = [1e-06 Inf];
            app.LearningratedropfactorSpinner_2.Value = 0.3;
            app.PatchsizeSpinner_2Label = uilabel(app.TrainTab);
            app.PatchsizeSpinner_2Label.Text = 'Patch size';
            app.PatchsizeSpinner_2 = uispinner(app.TrainTab);
            app.PatchsizeSpinner_2.Step = 32;
            app.PatchsizeSpinner_2.ValueChangingFcn = createCallbackFcn(app, @PatchsizeSpinner_2ValueChanging, true);
            app.PatchsizeSpinner_2.Limits = [32 Inf];
            app.PatchsizeSpinner_2.Value = 128;
            app.AugmentationPanel = uipanel(app.TrainTab);
            app.AugmentationPanel.Title = 'Augmentation';
            app.Rotation90CheckBox = uicheckbox(app.AugmentationPanel);
            app.Rotation90CheckBox.Text = 'Rotation 90 º';
            app.Rotation90CheckBox.Value = true;
            app.FliphorizonalCheckBox = uicheckbox(app.AugmentationPanel);
            app.FliphorizonalCheckBox.Text = 'Flip horizonal';
            app.FliphorizonalCheckBox.Value = true;
            app.FlipverticalCheckBox = uicheckbox(app.AugmentationPanel);
            app.FlipverticalCheckBox.Text = 'Flip vertical';
            app.FlipverticalCheckBox.Value = true;
            app.TrainigdataPanel = uipanel(app.TrainTab);
            app.TrainigdataPanel.Title = 'Trainig data';
            app.TrainingdataListBox = uilistbox(app.TrainigdataPanel);
            app.TrainingdataListBox.Items = {};
            app.TrainingdataListBox.Value = {};
            app.ValidationdataPanel = uipanel(app.TrainTab);
            app.ValidationdataPanel.Title = 'Validation data';
            app.TrainingdataListBox_2 = uilistbox(app.ValidationdataPanel);
            app.TrainingdataListBox_2.Items = {};
            app.TrainingdataListBox_2.Value = {};
            app.StatusLabel = uilabel(app.TrainTab);
            app.StatusLabel.Text = 'Status';
            app.Button = uibutton(app.TrainTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.Button_2 = uibutton(app.TrainTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.BatchsizeSpinnerLabel = uilabel(app.TrainTab);
            app.BatchsizeSpinnerLabel.Text = 'Batch size';
            app.BatchsizeSpinner = uispinner(app.TrainTab);
            app.BatchsizeSpinner.Limits = [1 Inf];
            app.BatchsizeSpinner.RoundFractionalValues = 'on';
            app.BatchsizeSpinner.Value = 32;
            app.PredictTab = uitab(app.TabGroup);
            app.PredictTab.Title = 'Predict';
            app.SelecttrainednetworkandfolderwithimagestopredictLabel = uilabel(app.PredictTab);
            app.SelecttrainednetworkandfolderwithimagestopredictLabel.Text = 'Select trained network and folder with images to predict:';
            app.networkEditFieldLabel = uilabel(app.PredictTab);
            app.networkEditFieldLabel.Text = 'network';
            app.networkEditField = uieditfield(app.PredictTab, 'text');
            app.datafolderEditFieldLabel = uilabel(app.PredictTab);
            app.datafolderEditFieldLabel.Text = 'data folder';
            app.datafolderEditField = uieditfield(app.PredictTab, 'text');
            app.PredictButton = uibutton(app.PredictTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.DatatopredictPanel = uipanel(app.PredictTab);
            app.DatatopredictPanel.Title = 'Data to predict';
            app.TrainingdataListBox_3 = uilistbox(app.DatatopredictPanel);
            app.TrainingdataListBox_3.Items = {};
            app.TrainingdataListBox_3.Value = {};
            app.StatusLabel_2 = uilabel(app.PredictTab);
            app.StatusLabel_2.Text = 'Status';
            app.Button_3 = uibutton(app.PredictTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.Button_4 = uibutton(app.PredictTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end